classdef register_2p_to_wf < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        LoadfilesTab              matlab.ui.container.Tab
        PlotinmicronsCheckBox     matlab.ui.control.CheckBox
        SaveButton                matlab.ui.control.Button
        SaveDBpathEditField       matlab.ui.control.EditField
        SaveDBpathEditFieldLabel  matlab.ui.control.Label
        xlsxpathEditField         matlab.ui.control.EditField
        xlsxpathEditFieldLabel    matlab.ui.control.Label
        LoadButton                matlab.ui.control.Button
        DBpathEditField           matlab.ui.control.EditField
        DBpathEditFieldLabel      matlab.ui.control.Label
        RegisterfovTab            matlab.ui.container.Tab
        FOV_clim                  matlab.ui.container.Panel
        ResetButton               matlab.ui.control.Button
        CLimmaxEditField          matlab.ui.control.NumericEditField
        CLimmaxEditFieldLabel     matlab.ui.control.Label
        CLimminEditField          matlab.ui.control.NumericEditField
        CLimminEditFieldLabel     matlab.ui.control.Label
        Panel                     matlab.ui.container.Panel
        CreatetformButton         matlab.ui.control.Button
        UpdatetformButton         matlab.ui.control.Button
        EraseTformButton          matlab.ui.control.Button
        ClonetformButton          matlab.ui.control.Button
        RemovepointButton         matlab.ui.control.Button
        AddpointButton            matlab.ui.control.Button
        UITablePoints             matlab.ui.control.Table
        PointsonButton            matlab.ui.control.StateButton
        RegionDropDown            matlab.ui.control.DropDown
        RegionDropDownLabel       matlab.ui.control.Label
        ButtonGroup               matlab.ui.container.ButtonGroup
        ApplytformallButton       matlab.ui.control.ToggleButton
        ApplytformButton          matlab.ui.control.ToggleButton
        OffButton                 matlab.ui.control.ToggleButton
        TformDropDown             matlab.ui.control.DropDown
        TformDropDownLabel        matlab.ui.control.Label
        FOVimageDropDown          matlab.ui.control.DropDown
        FOVimageDropDownLabel     matlab.ui.control.Label
        WFimageDropDown           matlab.ui.control.DropDown
        WFimageDropDownLabel      matlab.ui.control.Label
        FOV_axes                  matlab.ui.control.UIAxes
        WF_axes                   matlab.ui.control.UIAxes
        AlignWFTab                matlab.ui.container.Tab
        ContourButton             matlab.ui.control.StateButton
        RemoveregionButton        matlab.ui.control.Button
        AddregionEditField        matlab.ui.control.EditField
        AddregionButton           matlab.ui.control.Button
        WFimageDropDownMapping    matlab.ui.control.DropDown
        WFimageDropDown_2Label    matlab.ui.control.Label
        UITableMapping            matlab.ui.control.Table
        WF_axes_mapping           matlab.ui.control.UIAxes
        RegistermouseTab          matlab.ui.container.Tab
        regionnumSpinner          matlab.ui.control.Spinner
        regionnumSpinnerLabel     matlab.ui.control.Label
        drawregionButton          matlab.ui.control.Button
        regionbordersonButton     matlab.ui.control.StateButton
        AnchordsetSpinner         matlab.ui.control.Spinner
        AnchordsetSpinnerLabel    matlab.ui.control.Label
        RegisteronButton          matlab.ui.control.StateButton
        FreqvalEditField          matlab.ui.control.EditField
        FreqvalEditFieldLabel     matlab.ui.control.Label
        freqnumberSpinner         matlab.ui.control.Spinner
        freqnumberSpinnerLabel    matlab.ui.control.Label
        CoordsonButton            matlab.ui.control.StateButton
        WFimonButton              matlab.ui.control.StateButton
        mousenumberSpinner        matlab.ui.control.Spinner
        mousenumberSpinnerLabel   matlab.ui.control.Label
        UITableMapping_mouse      matlab.ui.control.Table
        WF_axes_mapping_mouse     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        ops; % Description
        data_xlsx;
        data_all = [];
        app_data = struct();
        fov_axes;
        wf_axes;
        wf_axes_map;
        wf_axes_map_mouse;
        wf_axes_map_mouse_plt;
        pt2p;
        ptwf;
        UITablePointsSelection;
        current_mapping_plot = 1;
        mapping_regions;
        map_pt_colors = {'m', 'g', 'b', 'r', 'c', 'y', 'k'};
        map_pt;
        map_current_tab_selection;
        current_fov_is_surface = 1;
        wf_tform_all;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ops = GUI_params();
            f_reg_initialize(app);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            f_reg_load_data(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            f_reg_save_data(app);
        end

        % Value changed function: WFimageDropDown
        function WFimageDropDownValueChanged(app, event)
            f_reg_update_dropdown(app);
            f_reg_update_plot_wf(app);
            f_reg_update_plot_fov(app);
            f_reg_point_on(app);
        end

        % Value changed function: FOVimageDropDown
        function FOVimageDropDownValueChanged(app, event)
            f_reg_update_dropdown(app);
            f_reg_update_plot_wf(app);
            f_reg_update_plot_fov(app);  
        end

        % Value changed function: RegionDropDown
        function RegionDropDownValueChanged(app, event)
            f_reg_update_dropdown(app);
            f_reg_update_plot_wf(app);
            f_reg_update_plot_fov(app);
            f_reg_point_on(app);
        end

        % Value changed function: TformDropDown
        function TformDropDownValueChanged(app, event)
            f_reg_update_ctform(app);
            f_reg_update_plot_wf(app);
            f_reg_points_remove(app);
            f_reg_points_add(app);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            f_reg_update_plot_wf(app);
            f_reg_point_on(app);
        end

        % Value changed function: PointsonButton
        function PointsonButtonValueChanged(app, event)
            f_reg_point_on(app);
        end

        % Button pushed function: ClonetformButton
        function ClonetformButtonPushed(app, event)
            f_reg_tform_clone(app);
        end

        % Button pushed function: EraseTformButton
        function EraseTformButtonPushed(app, event)
            f_reg_tform_erase(app);
        end

        % Button pushed function: AddpointButton
        function AddpointButtonPushed(app, event)
            f_reg_point_add_pt(app);
        end

        % Button pushed function: RemovepointButton
        function RemovepointButtonPushed(app, event)
            f_reg_point_remove_pt(app);
        end

        % Cell selection callback: UITablePoints
        function UITablePointsCellSelection(app, event)
            app.UITablePointsSelection = event.Indices;
        end

        % Button pushed function: UpdatetformButton
        function UpdatetformButtonPushed(app, event)
            f_reg_update_tform(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            f_reg_reset_clim(app);
        end

        % Value changed function: CLimminEditField
        function CLimminEditFieldValueChanged(app, event)
            f_reg_set_clim(app);
        end

        % Value changed function: CLimmaxEditField
        function CLimmaxEditFieldValueChanged(app, event)
            f_reg_set_clim(app);
        end

        % Button pushed function: CreatetformButton
        function CreatetformButtonPushed(app, event)
            f_reg_create_tfrom(app);
        end

        % Value changed function: WFimageDropDownMapping
        function WFimageDropDownMappingValueChanged(app, event)
            f_reg_align_point_remove(app);
            f_reg_align_update_table(app);
            f_reg_align_update_plot_wf(app);
            f_reg_align_point_add(app);
        end

        % Cell selection callback: UITableMapping
        function UITableMappingCellSelection(app, event)
            f_reg_align_point_save(app);
            f_reg_align_point_remove(app);
            f_reg_align_update_current_mapping(app, event);
            f_reg_align_update_plot_wf(app);
            f_reg_align_point_add(app);
        end

        % Button pushed function: AddregionButton
        function AddregionButtonPushed(app, event)
            f_reg_align_region_add(app);
            f_reg_align_update_table(app);
            f_reg_align_point_save(app);
            f_reg_align_point_remove(app);
            f_reg_align_point_add(app);
        end

        % Button pushed function: RemoveregionButton
        function RemoveregionButtonPushed(app, event)
            f_reg_align_region_remove(app);
        end

        % Cell edit callback: UITableMapping
        function UITableMappingCellEdit(app, event)
            f_reg_align_table_val_changed(app, event);
            f_reg_align_point_save(app);
            f_reg_align_point_remove(app);
            f_reg_align_point_add(app);
        end

        % Value changed function: ContourButton
        function ContourButtonValueChanged(app, event)
            f_reg_contour_plot(app);
        end

        % Value changed function: mousenumberSpinner
        function mousenumberSpinnerValueChanged(app, event)
            f_reg_update_mouse_align(app);
        end

        % Value changed function: freqnumberSpinner
        function freqnumberSpinnerValueChanged(app, event)
            f_reg_update_mouse_align(app);
        end

        % Value changed function: RegisteronButton
        function RegisteronButtonValueChanged(app, event)
            f_reg_register_wf(app);
        end

        % Value changed function: regionbordersonButton
        function regionbordersonButtonValueChanged(app, event)
            f_reg_region_borders(app);
        end

        % Button pushed function: drawregionButton
        function drawregionButtonPushed(app, event)
            f_reg_region_draw(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LoadfilesTab = uitab(app.TabGroup);
            app.LoadfilesTab.Tooltip = {''};
            app.LoadfilesTab.Title = 'Load files';
            app.DBpathEditFieldLabel = uilabel(app.LoadfilesTab);
            app.DBpathEditFieldLabel.Text = 'DB path';
            app.DBpathEditField = uieditfield(app.LoadfilesTab, 'text');
            app.LoadButton = uibutton(app.LoadfilesTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Tooltip = {'Input mat file with:'; 'wf_data table with columns: {''mouse_tag'' ''wf_fname'' ''wf_im''} '; 'fov_data table with columns {''mouse_tag'' ''wf_fname'' ''area'' ''fov_fname''  ''fov_im''}'};
            app.LoadButton.Text = 'Load';
            app.xlsxpathEditFieldLabel = uilabel(app.LoadfilesTab);
            app.xlsxpathEditFieldLabel.Text = 'xlsx path';
            app.xlsxpathEditField = uieditfield(app.LoadfilesTab, 'text');
            app.SaveDBpathEditFieldLabel = uilabel(app.LoadfilesTab);
            app.SaveDBpathEditFieldLabel.Text = 'Save DB path';
            app.SaveDBpathEditField = uieditfield(app.LoadfilesTab, 'text');
            app.SaveButton = uibutton(app.LoadfilesTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.PlotinmicronsCheckBox = uicheckbox(app.LoadfilesTab);
            app.PlotinmicronsCheckBox.Text = 'Plot in microns';
            app.PlotinmicronsCheckBox.Value = true;
            app.RegisterfovTab = uitab(app.TabGroup);
            app.RegisterfovTab.Title = 'Register fov';
            app.WF_axes = uiaxes(app.RegisterfovTab);
            title(app.WF_axes, 'Widefield')
            app.FOV_axes = uiaxes(app.RegisterfovTab);
            title(app.FOV_axes, 'FOV')
            app.WFimageDropDownLabel = uilabel(app.RegisterfovTab);
            app.WFimageDropDownLabel.Text = 'WF image';
            app.WFimageDropDown = uidropdown(app.RegisterfovTab);
            app.WFimageDropDown.Items = {};
            app.WFimageDropDown.ValueChangedFcn = createCallbackFcn(app, @WFimageDropDownValueChanged, true);
            app.WFimageDropDown.Value = {};
            app.FOVimageDropDownLabel = uilabel(app.RegisterfovTab);
            app.FOVimageDropDownLabel.Text = 'FOV image';
            app.FOVimageDropDown = uidropdown(app.RegisterfovTab);
            app.FOVimageDropDown.Items = {};
            app.FOVimageDropDown.ValueChangedFcn = createCallbackFcn(app, @FOVimageDropDownValueChanged, true);
            app.FOVimageDropDown.Value = {};
            app.TformDropDownLabel = uilabel(app.RegisterfovTab);
            app.TformDropDownLabel.Text = 'Tform';
            app.TformDropDown = uidropdown(app.RegisterfovTab);
            app.TformDropDown.Items = {};
            app.TformDropDown.ValueChangedFcn = createCallbackFcn(app, @TformDropDownValueChanged, true);
            app.TformDropDown.Value = {};
            app.ButtonGroup = uibuttongroup(app.RegisterfovTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.OffButton = uitogglebutton(app.ButtonGroup);
            app.OffButton.Text = 'Off';
            app.OffButton.Value = true;
            app.ApplytformButton = uitogglebutton(app.ButtonGroup);
            app.ApplytformButton.Text = 'Apply tform';
            app.ApplytformallButton = uitogglebutton(app.ButtonGroup);
            app.ApplytformallButton.Text = 'Apply tform all';
            app.RegionDropDownLabel = uilabel(app.RegisterfovTab);
            app.RegionDropDownLabel.Text = 'Region';
            app.RegionDropDown = uidropdown(app.RegisterfovTab);
            app.RegionDropDown.Items = {};
            app.RegionDropDown.ValueChangedFcn = createCallbackFcn(app, @RegionDropDownValueChanged, true);
            app.RegionDropDown.Value = {};
            app.Panel = uipanel(app.RegisterfovTab);
            app.Panel.Title = 'Panel';
            app.PointsonButton = uibutton(app.Panel, 'state');
            app.PointsonButton.ValueChangedFcn = createCallbackFcn(app, @PointsonButtonValueChanged, true);
            app.PointsonButton.Text = 'Points on';
            app.UITablePoints = uitable(app.Panel);
            app.UITablePoints.ColumnName = {'Points'};
            app.UITablePoints.RowName = {};
            app.UITablePoints.CellSelectionCallback = createCallbackFcn(app, @UITablePointsCellSelection, true);
            app.AddpointButton = uibutton(app.Panel, 'push');
            app.AddpointButton.ButtonPushedFcn = createCallbackFcn(app, @AddpointButtonPushed, true);
            app.AddpointButton.Text = 'Add point';
            app.RemovepointButton = uibutton(app.Panel, 'push');
            app.RemovepointButton.ButtonPushedFcn = createCallbackFcn(app, @RemovepointButtonPushed, true);
            app.RemovepointButton.Text = 'Remove point';
            app.ClonetformButton = uibutton(app.Panel, 'push');
            app.ClonetformButton.ButtonPushedFcn = createCallbackFcn(app, @ClonetformButtonPushed, true);
            app.ClonetformButton.Text = 'Clone tform';
            app.EraseTformButton = uibutton(app.Panel, 'push');
            app.EraseTformButton.ButtonPushedFcn = createCallbackFcn(app, @EraseTformButtonPushed, true);
            app.EraseTformButton.Text = 'Erase Tform';
            app.UpdatetformButton = uibutton(app.Panel, 'push');
            app.UpdatetformButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatetformButtonPushed, true);
            app.UpdatetformButton.Text = 'Update tform';
            app.CreatetformButton = uibutton(app.Panel, 'push');
            app.CreatetformButton.ButtonPushedFcn = createCallbackFcn(app, @CreatetformButtonPushed, true);
            app.CreatetformButton.Text = 'Create tform';
            app.FOV_clim = uipanel(app.RegisterfovTab);
            app.CLimminEditFieldLabel = uilabel(app.FOV_clim);
            app.CLimminEditFieldLabel.Text = 'CLim min ';
            app.CLimminEditField = uieditfield(app.FOV_clim, 'numeric');
            app.CLimminEditField.Limits = [0 1];
            app.CLimminEditField.ValueChangedFcn = createCallbackFcn(app, @CLimminEditFieldValueChanged, true);
            app.CLimmaxEditFieldLabel = uilabel(app.FOV_clim);
            app.CLimmaxEditFieldLabel.Text = 'CLim max';
            app.CLimmaxEditField = uieditfield(app.FOV_clim, 'numeric');
            app.CLimmaxEditField.Limits = [0 Inf];
            app.CLimmaxEditField.ValueChangedFcn = createCallbackFcn(app, @CLimmaxEditFieldValueChanged, true);
            app.CLimmaxEditField.Value = 1;
            app.ResetButton = uibutton(app.FOV_clim, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.AlignWFTab = uitab(app.TabGroup);
            app.AlignWFTab.Title = 'Align WF';
            app.WF_axes_mapping = uiaxes(app.AlignWFTab);
            title(app.WF_axes_mapping, 'Tonotopy alignment')
            app.UITableMapping = uitable(app.AlignWFTab);
            app.UITableMapping.ColumnName = {'Area'};
            app.UITableMapping.RowName = {};
            app.UITableMapping.ColumnEditable = true;
            app.UITableMapping.CellEditCallback = createCallbackFcn(app, @UITableMappingCellEdit, true);
            app.UITableMapping.CellSelectionCallback = createCallbackFcn(app, @UITableMappingCellSelection, true);
            app.WFimageDropDown_2Label = uilabel(app.AlignWFTab);
            app.WFimageDropDown_2Label.Text = 'WF image';
            app.WFimageDropDownMapping = uidropdown(app.AlignWFTab);
            app.WFimageDropDownMapping.Items = {};
            app.WFimageDropDownMapping.ValueChangedFcn = createCallbackFcn(app, @WFimageDropDownMappingValueChanged, true);
            app.WFimageDropDownMapping.Value = {};
            app.AddregionButton = uibutton(app.AlignWFTab, 'push');
            app.AddregionButton.ButtonPushedFcn = createCallbackFcn(app, @AddregionButtonPushed, true);
            app.AddregionButton.Text = 'Add region';
            app.AddregionEditField = uieditfield(app.AlignWFTab, 'text');
            app.RemoveregionButton = uibutton(app.AlignWFTab, 'push');
            app.RemoveregionButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveregionButtonPushed, true);
            app.RemoveregionButton.Text = 'Remove region';
            app.ContourButton = uibutton(app.AlignWFTab, 'state');
            app.ContourButton.ValueChangedFcn = createCallbackFcn(app, @ContourButtonValueChanged, true);
            app.ContourButton.Text = 'Contour';
            app.RegistermouseTab = uitab(app.TabGroup);
            app.RegistermouseTab.Title = 'Register mouse';
            app.WF_axes_mapping_mouse = uiaxes(app.RegistermouseTab);
            title(app.WF_axes_mapping_mouse, ' Mouse alignment')
            app.UITableMapping_mouse = uitable(app.RegistermouseTab);
            app.UITableMapping_mouse.ColumnName = {'Freq'};
            app.UITableMapping_mouse.RowName = {};
            app.UITableMapping_mouse.ColumnEditable = true;
            app.mousenumberSpinnerLabel = uilabel(app.RegistermouseTab);
            app.mousenumberSpinnerLabel.Text = 'mouse number';
            app.mousenumberSpinner = uispinner(app.RegistermouseTab);
            app.mousenumberSpinner.Limits = [1 Inf];
            app.mousenumberSpinner.RoundFractionalValues = 'on';
            app.mousenumberSpinner.ValueChangedFcn = createCallbackFcn(app, @mousenumberSpinnerValueChanged, true);
            app.mousenumberSpinner.Value = 1;
            app.WFimonButton = uibutton(app.RegistermouseTab, 'state');
            app.WFimonButton.Text = 'WF im on';
            app.CoordsonButton = uibutton(app.RegistermouseTab, 'state');
            app.CoordsonButton.Text = 'Coords on';
            app.freqnumberSpinnerLabel = uilabel(app.RegistermouseTab);
            app.freqnumberSpinnerLabel.Text = 'freq number';
            app.freqnumberSpinner = uispinner(app.RegistermouseTab);
            app.freqnumberSpinner.Limits = [1 Inf];
            app.freqnumberSpinner.RoundFractionalValues = 'on';
            app.freqnumberSpinner.ValueChangedFcn = createCallbackFcn(app, @freqnumberSpinnerValueChanged, true);
            app.freqnumberSpinner.Value = 1;
            app.FreqvalEditFieldLabel = uilabel(app.RegistermouseTab);
            app.FreqvalEditFieldLabel.Text = 'Freq val';
            app.FreqvalEditField = uieditfield(app.RegistermouseTab, 'text');
            app.RegisteronButton = uibutton(app.RegistermouseTab, 'state');
            app.RegisteronButton.ValueChangedFcn = createCallbackFcn(app, @RegisteronButtonValueChanged, true);
            app.RegisteronButton.Text = 'Register on';
            app.AnchordsetSpinnerLabel = uilabel(app.RegistermouseTab);
            app.AnchordsetSpinnerLabel.Text = 'Anchor dset';
            app.AnchordsetSpinner = uispinner(app.RegistermouseTab);
            app.AnchordsetSpinner.Limits = [1 Inf];
            app.AnchordsetSpinner.RoundFractionalValues = 'on';
            app.AnchordsetSpinner.Value = 1;
            app.regionbordersonButton = uibutton(app.RegistermouseTab, 'state');
            app.regionbordersonButton.ValueChangedFcn = createCallbackFcn(app, @regionbordersonButtonValueChanged, true);
            app.regionbordersonButton.Text = 'region borders on';
            app.drawregionButton = uibutton(app.RegistermouseTab, 'push');
            app.drawregionButton.ButtonPushedFcn = createCallbackFcn(app, @drawregionButtonPushed, true);
            app.drawregionButton.Text = 'draw region';
            app.regionnumSpinnerLabel = uilabel(app.RegistermouseTab);
            app.regionnumSpinnerLabel.Text = 'region num';
            app.regionnumSpinner = uispinner(app.RegistermouseTab);
            app.regionnumSpinner.Limits = [1 Inf];
            app.regionnumSpinner.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = register_2p_to_wf

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end